classdef HoloApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ModePanel                     matlab.ui.container.Panel
        BackLayout                    matlab.ui.container.GridLayout
        SideLayout                    matlab.ui.container.GridLayout
        LogoNavLayout                 matlab.ui.container.GridLayout
        NavigationLayout              matlab.ui.container.GridLayout
        ShiftButton                   matlab.ui.control.StateButton
        LoadDataButton                matlab.ui.control.StateButton
        ResultsButton                 matlab.ui.control.StateButton
        ReconstructionButton          matlab.ui.control.StateButton
        FocusButton                   matlab.ui.control.StateButton
        Image                         matlab.ui.control.Image
        MainLayout                    matlab.ui.container.GridLayout
        NavigationTab                 matlab.ui.container.TabGroup
        LoadDataTab                   matlab.ui.container.Tab
        LoadDataLayout                matlab.ui.container.GridLayout
        LoadDataDisplayLayout         matlab.ui.container.GridLayout
        LoadDataDisplayButtonsLayout  matlab.ui.container.GridLayout
        LoadDataNextButton            matlab.ui.control.Button
        LoadDataPreviousButton        matlab.ui.control.Button
        LoadDataUIAxes                matlab.ui.control.UIAxes
        LoadDataControlLayout         matlab.ui.container.GridLayout
        GridLayout                    matlab.ui.container.GridLayout
        WavelengthEditField           matlab.ui.control.NumericEditField
        WavelengthmEditFieldLabel     matlab.ui.control.Label
        PixelSizeEditField            matlab.ui.control.NumericEditField
        PixelSizemLabel               matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        HeightmLabel                  matlab.ui.control.Label
        ModeTabGroup                  matlab.ui.container.TabGroup
        MultiwavelenghtTab            matlab.ui.container.Tab
        GridLayout2_2                 matlab.ui.container.GridLayout
        PhotoWavelengthTable          matlab.ui.control.Table
        MultiheightTab                matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        PhotoHeightTable              matlab.ui.control.Table
        ModeLabel                     matlab.ui.control.Label
        ModeDropDown                  matlab.ui.control.DropDown
        Label                         matlab.ui.control.Label
        DefaultvaluesLabel            matlab.ui.control.Label
        LoadHologramsButton           matlab.ui.control.Button
        LoadDataFilesPanel            matlab.ui.container.Panel
        LoadDataFileListBoxLayout     matlab.ui.container.GridLayout
        LoadDataFilesListBox          matlab.ui.control.ListBox
        FilesListBox_3Label           matlab.ui.control.Label
        FocusTab                      matlab.ui.container.Tab
        FocusLayout                   matlab.ui.container.GridLayout
        FocusDisplayLayout            matlab.ui.container.GridLayout
        FocusDisplayButtonsLayout     matlab.ui.container.GridLayout
        PhaseButton                   matlab.ui.control.StateButton
        AmplitudeButton               matlab.ui.control.StateButton
        FocusNextButton               matlab.ui.control.Button
        FocusPreviousButton           matlab.ui.control.Button
        FocusUIAxes                   matlab.ui.control.UIAxes
        FocusControlLayout            matlab.ui.container.GridLayout
        FocusingPanel                 matlab.ui.container.Panel
        AdjustFocusLayout             matlab.ui.container.GridLayout
        AdjustFocusLabel              matlab.ui.control.Label
        SweepAutoFocusLayout          matlab.ui.container.GridLayout
        AutofocusButton               matlab.ui.control.Button
        SweepStopEditField            matlab.ui.control.NumericEditField
        SweepStopLabel                matlab.ui.control.Label
        DarkFocusRangeEditField       matlab.ui.control.NumericEditField
        SweepStepLabel                matlab.ui.control.Label
        SweepStartEditField           matlab.ui.control.NumericEditField
        StartmLabel                   matlab.ui.control.Label
        DarkfocusLabel                matlab.ui.control.Label
        ControlLayout_2               matlab.ui.container.GridLayout
        SubtractButton                matlab.ui.control.StateButton
        AddButton                     matlab.ui.control.StateButton
        CurrentHeightEditField        matlab.ui.control.NumericEditField
        CurrentHeightmLabel           matlab.ui.control.Label
        ControlLayout                 matlab.ui.container.GridLayout
        StepEditField                 matlab.ui.control.NumericEditField
        StepLabel                     matlab.ui.control.Label
        FocusFilesPanel               matlab.ui.container.Panel
        FocusFileListBoxLayout        matlab.ui.container.GridLayout
        FocusFilesListBox             matlab.ui.control.ListBox
        FilesListBox_2Label           matlab.ui.control.Label
        ShiftTab                      matlab.ui.container.Tab
        ShiftLayout                   matlab.ui.container.GridLayout
        ShiftControlLayout            matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        ShiftHologramTypeDropDown     matlab.ui.control.DropDown
        GridLayout3_2                 matlab.ui.container.GridLayout
        AutocorrectButton             matlab.ui.control.Button
        CorrectShiftLabel_2           matlab.ui.control.Label
        ShiftFilesPanel               matlab.ui.container.Panel
        ShiftFileListBoxLayout        matlab.ui.container.GridLayout
        ShiftFilesListBox             matlab.ui.control.ListBox
        FilesListBox_3Label_2         matlab.ui.control.Label
        ShiftDisplayLayout            matlab.ui.container.GridLayout
        ShiftDisplayButtonsLayout     matlab.ui.container.GridLayout
        ShiftPhaseButton              matlab.ui.control.StateButton
        ShiftAmplitudeButton          matlab.ui.control.StateButton
        ShiftNextButton               matlab.ui.control.Button
        ShiftPreviousButton           matlab.ui.control.Button
        ShiftUIAxes                   matlab.ui.control.UIAxes
        ReconstructionTab             matlab.ui.container.Tab
        LoadDataLayout_3              matlab.ui.container.GridLayout
        ShiftControlLayout_2          matlab.ui.container.GridLayout
        GridLayout3_3                 matlab.ui.container.GridLayout
        StartReconstruction           matlab.ui.control.Button
        ReconstructioncontrolLabel    matlab.ui.control.Label
        ShiftFilesPanel_2             matlab.ui.container.Panel
        ShiftFileListBoxLayout_2      matlab.ui.container.GridLayout
        ShiftFilesListBox_2           matlab.ui.control.ListBox
        FilesListBox_3Label_3         matlab.ui.control.Label
        ShiftDisplayLayout_2          matlab.ui.container.GridLayout
        ShiftDisplayButtonsLayout_2   matlab.ui.container.GridLayout
        PhaseButton_3                 matlab.ui.control.StateButton
        AmplitudeButton_3             matlab.ui.control.StateButton
        ShiftNextButton_2             matlab.ui.control.Button
        ShiftPreviousButton_2         matlab.ui.control.Button
        ShiftUIAxes_2                 matlab.ui.control.UIAxes
        ResultsTab                    matlab.ui.container.Tab
        ResultsLayout                 matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        Files
        QCIModel QCI_Model = QCI_Model()% Model used for hologram transforms
        CurrentHologramIndex (1,1) uint16 = 1  % index of selected hologram
    end
    
    methods (Access = private)
        %% Model
        function LoadPhotosToHologramModel(app)
            if(~isempty(fieldnames(app.Files)))

                imageNumber = length(app.Files);
                names = strings(1,imageNumber);
                
                % Loading images cell array from .m files
                for i = 1:imageNumber
                    names(i) = app.Files(i).name;
                    images{i} = struct2cell(load(fullfile(app.Files(i).folder,app.Files(i).name)));
                end

                % Getting default lambdas, heights, and camera pixel sizes
                wavelengths = ones(1,imageNumber)*app.WavelengthEditField.Value;
                heights = ones(1,imageNumber)*app.HeightEditField.Value;
                pixelSize = app.PixelSizeEditField.Value;

                % Initializing the model
                app.QCIModel = QCI_Model(names, images, wavelengths, heights, pixelSize);
            end
        end
        %% View
        function LoadPhotosToFilesListBox(app)
            if(~isempty(fieldnames(app.Files)))
                
                for i = 1:length(app.Files)
                    %Names in the list box
                    loadDataFileName = app.Files(i).name;
                    fileName = strsplit(loadDataFileName,"."); 
                    FocusName = "AfterFocus" + fileName(1);
                    ShiftName = FocusName;
                    

                    app.LoadDataFilesListBox.Items = [app.LoadDataFilesListBox.Items, loadDataFileName];
                    app.FocusFilesListBox.Items = [app.FocusFilesListBox.Items, FocusName];
                    app.ShiftFilesListBox.Items = [app.ShiftFilesListBox.Items, ShiftName];
                end

                app.LoadDataFilesListBox.ItemsData = [1:length(app.Files)];
                app.LoadDataFilesListBox.Value = app.CurrentHologramIndex;
                app.FocusFilesListBox.ItemsData = [1:length(app.Files)];
                app.FocusFilesListBox.Value = app.CurrentHologramIndex;
                app.ShiftFilesListBox.ItemsData = [1:length(app.Files)];
                app.ShiftFilesListBox.Value = app.CurrentHologramIndex;
            end
        end
        
        function HandleFileChange(app,newFileIndex)
            if(isempty(fieldnames(app.Files)))
                return
            end
            
            if(newFileIndex <= length(app.Files) && newFileIndex >= 1)
                app.CurrentHologramIndex = newFileIndex;
            elseif (newFileIndex < 1)
                app.CurrentHologramIndex = 1;
            elseif(newFileIndex > length(app.Files))
                app.CurrentHologramIndex = mod(newFileIndex,5);
            end
            
            app.LoadDataFilesListBox.Value = app.CurrentHologramIndex;
            app.FocusFilesListBox.Value = app.CurrentHologramIndex;

            if(app.NavigationTab.SelectedTab == app.LoadDataTab)
                image = app.QCIModel.getImage(app.CurrentHologramIndex);
                imshow(image,"Parent",app.LoadDataUIAxes);
            elseif(app.NavigationTab.SelectedTab == app.FocusTab)
                heights = app.QCIModel.getZCoordinates();
                app.CurrentHeightEditField.Value=heights(app.CurrentHologramIndex);
                app.DisplayFocus();
            elseif(app.NavigationTab.SelectedTab == app.ShiftTab)
                app.DisplayShift();
            end
        end
        
        function HandleModeChange(app, newModeValue)
            switch newModeValue
                case '1' %SingleHologram
                    app.ModeTabGroup.Visible = "off";
                case '2' %Multi-wavelength
                    app.ModeTabGroup.SelectedTab = app.MultiwavelenghtTab;
                    app.ModeTabGroup.Visible = "on";
                case '3' %Multi-height
                    app.ModeTabGroup.SelectedTab = app.MultiheightTab;
                    app.ModeTabGroup.Visible = "on";
                otherwise
                    return
            end
        end
        
        function LoadModeTableData(app)
            heights = app.QCIModel.getZCoordinates();
            wavelengths = app.QCIModel.getWavelengths();
            names = app.QCIModel.getNames();
            app.PhotoHeightTable.Data =[names.' heights.'];
            app.PhotoWavelengthTable.Data = [names.' wavelengths.'];
        end

        function DisplayFocus(app)
            heights = app.QCIModel.getZCoordinates();
            image = app.QCIModel.propagate(app.CurrentHeightEditField.Value, app.CurrentHologramIndex);

            if(app.AmplitudeButton.Value == 1)
                image = abs(image);
                imshow(uint8(image),"Parent",app.FocusUIAxes);
            else
                image = angle(image);
                imshow(image,"Parent",app.FocusUIAxes);
            end
        end

        function DisplayShift(app)
            switch(app.ShiftHologramTypeDropDown)
                case '1' %Reference
                    app.ShiftAmplitudeButton.Enable = "off";
                    app.ShiftAmplitudeButton.Enable = "off";
                    image = app.QCIModel.getHologramPostPropagation(app.CurrentHologramIndex);
                    imshow(uint8(image),"Parent",app.FocusUIAxes);
                    return
                case '2' % Holograms
                    image = app.QCIModel.getImage(app.CurrentHologramIndex);
                case '3' %
                   image = app.QCIModel.getCorrectedReference(app.CurrentHologramIndex);
                case '4'
                   image = app.QCIModel.getCorrectedHologram(app.CurrentHologramIndex);
            end

            if(app.AmplitudeButton.Value == 1)
                image = abs(image);
                imshow(uint8(image),"Parent",app.FocusUIAxes);
            else
                image = angle(image);
                imshow(image,"Parent",app.FocusUIAxes);
            end
        end

        function DisableInterface(app)
            % Menu buttons
            app.LoadDataButton.Enable = "off";
            app.FocusButton.Enable = "off";
            app.ReconstructionButton.Enable = "off";
            app.ResultsButton.Enable = "off";

            % Load Data
            app.LoadDataFilesListBox.Enable = "off";
            app.LoadHologramsButton.Enable = "off";
            app.PixelSizeEditField.Enable = "off";
            app.HeightEditField.Enable = "off";
            app.ModeDropDown.Enable = "off";
            app.PhotoWavelengthTable.Enable =  "off";
            app.PhotoHeightTable.Enable = "off";

            app.LoadDataNextButton.Enable = "off";
            app.LoadDataPreviousButton.Enable = "off";

            % Focus 
            app.FocusFilesListBox.Enable = "off";
            app.StepEditField.Enable = "off";
            app.SubtractButton.Enable = "off";
            app.CurrentHeightEditField.Enable = "off";
            app.AddButton.Enable = "off";

            app.AmplitudeButton.Enable = "off";
            app.PhaseButton.Enable = "off";
            app.FocusNextButton.Enable = "off";
            app.FocusPreviousButton.Enable = "off";
        end
        
        function EnableInterface(app)
            % Menu buttons
            app.LoadDataButton.Enable = "on";
            app.FocusButton.Enable = "on";
            app.ReconstructionButton.Enable = "on";
            app.ResultsButton.Enable = "on";

            % Load Data
            app.LoadDataFilesListBox.Enable = "on";
            app.LoadHologramsButton.Enable = "on";
            app.PixelSizeEditField.Enable = "on";
            app.HeightEditField.Enable = "on";
            app.ModeDropDown.Enable = "on";
            app.PhotoWavelengthTable.Enable =  "on";
            app.PhotoHeightTable.Enable = "on";

            app.LoadDataNextButton.Enable = "on";
            app.LoadDataPreviousButton.Enable = "on";

            % Focus and shift
            app.FocusFilesListBox.Enable = "on";
            app.StepEditField.Enable = "on";
            app.SubtractButton.Enable = "on";
            app.CurrentHeightEditField.Enable = "on";
            app.AddButton.Enable = "on";

            app.AmplitudeButton.Enable = "on";
            app.PhaseButton.Enable = "on";
            app.FocusNextButton.Enable = "on";
            app.FocusPreviousButton.Enable = "on";
        end
        
    end
    %Controller

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
           app.NavigationTab.SelectedTab = app.LoadDataTab;
           app.FocusButton.Value = 0;
           app.ShiftButton.Value = 0;
           app.ResultsButton.Value = 0;
           app.ReconstructionButton.Value = 0;
        end

        % Value changed function: FocusButton
        function FocusButtonValueChanged(app, event)
            % Selected button change
            app.NavigationTab.SelectedTab = app.FocusTab;
            app.LoadDataButton.Value = 0;
            app.ShiftButton.Value = 0;
            app.ResultsButton.Value = 0;
            app.ReconstructionButton.Value = 0;

            %Load the current propagation image;
            app.HandleFileChange(app.CurrentHologramIndex);
        end

        % Button pushed function: LoadHologramsButton
        function LoadHologramsButtonPushed(app, event)
             selectedPath = uigetdir();
             
             app.DisableInterface();

             %Load photos to the app.
             if(selectedPath ~= 0)
                 app.Files = [];
                 files = dir(selectedPath);
                 files(1:2) = [];

                 %Model
                 app.Files = files;
                 app.LoadPhotosToHologramModel();
                 app.CurrentHologramIndex = 1;

                 % View
                 app.LoadPhotosToFilesListBox();
                 app.LoadModeTableData();
                 
                image = app.QCIModel.getImage(app.CurrentHologramIndex);
                imshow(image, "Parent",app.LoadDataUIAxes);
             end

             app.EnableInterface();
        end

        % Value changed function: LoadDataFilesListBox
        function LoadDataFilesListBoxValueChanged(app, event)
            app.HandleFileChange(app.LoadDataFilesListBox.Value);
        end

        % Button pushed function: FocusNextButton, LoadDataNextButton
        function NextButtonValueChanged(app, event)
             app.HandleFileChange(app.CurrentHologramIndex + 1);          
        end

        % Button pushed function: FocusPreviousButton, 
        % ...and 1 other component
        function PreviousButtonValueChanged(app, event)
            app.HandleFileChange(app.CurrentHologramIndex - 1);
        end

        % Value changed function: FocusFilesListBox
        function FocusFilesListBoxValueChanged(app, event)
            app.HandleFileChange(app.FocusFilesListBox.Value);
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            HandleModeChange(app, app.ModeDropDown.Value);
        end

        % Value changed function: ShiftButton
        function ShiftButtonValueChanged(app, event)
            % Selected button change
            app.NavigationTab.SelectedTab = app.FocusTab;
            app.LoadDataButton.Value = 0;
            app.ShiftButton.Value = 0;
            app.ResultsButton.Value = 0;
            app.ReconstructionButton.Value = 0;

            app.HandleFileChange(app.CurrentHologramIndex);
        end

        % Cell edit callback: PhotoWavelengthTable
        function PhotoWavelengthTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            newWavelengths = app.QCIModel.getWavelengths();
            newWavelengths(indices(1)) = newData;

            app.QCIModel.setWavelenghts(newWavelengths);
        end

        % Cell edit callback: PhotoHeightTable
        function PhotoHeightTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            newHeights = app.QCIModel.ZCoordinates();
            newHeights(indices(1)) = newData;

            app.QCIModel.setHeights(newHeights);
        end

        % Value changed function: AmplitudeButton
        function AmplitudeButtonValueChanged(app, event)
            app.PhaseButton.Value = ~app.AmplitudeButton.Value;
            app.AmplitudeButton.Enable = "off";
            app.PhaseButton.Enable = "on";
            app.DisplayFocus();
        end

        % Value changed function: PhaseButton
        function PhaseButtonValueChanged(app, event)
            app.AmplitudeButton.Value = ~app.PhaseButton.Value;
            app.PhaseButton.Enable = "off";
            app.AmplitudeButton.Enable = "on";
            app.DisplayFocus();
        end

        % Value changed function: AddButton
        function AddButtonValueChanged(app, event)
            %View
            app.CurrentHeightEditField.Value =  app.CurrentHeightEditField.Value + app.StepEditField.Value;

            %Model
            heights = app.QCIModel.getZCoordinates();
            heights(app.CurrentHologramIndex) = app.CurrentHeightEditField.Value;
            app.QCIModel.setZCoordinates(heights);

            app.DisplayFocus();
            app.LoadModeTableData();
        end

        % Value changed function: SubtractButton
        function SubtractButtonValueChanged(app, event)
            %View
            app.CurrentHeightEditField.Value =  app.CurrentHeightEditField.Value - app.StepEditField.Value;

            %Model
            heights = app.QCIModel.getZCoordinates();
            heights(app.CurrentHologramIndex) = app.CurrentHeightEditField.Value;
            app.QCIModel.setZCoordinates(heights);

            app.DisplayFocus();
            app.LoadModeTableData();
        end

        % Value changed function: CurrentHeightEditField
        function CurrentHeightEditFieldValueChanged(app, event)
            %Model
            heights = app.QCIModel.getZCoordinates();
            heights(app.CurrentHologramIndex) = app.CurrentHeightEditField.Value;
            app.QCIModel.setZCoordinates(heights);

            app.DisplayFocus();
            app.LoadModeTableData();
        end

        % Button pushed function: AutocorrectButton
        function AutocorrectButtonPushed(app, event)
            [~, ~, correctedHeights] =app.QCIModel.automaticalShiftAndScalingCorrection();
        end

        % Value changed function: ShiftHologramTypeDropDown
        function ShiftHologramTypeDropDownValueChanged(app, event)
            app.ShiftHologramTypeDropDown.Items = [];
            switch(app.ShiftHologramTypeDropDown)
                case '1' %Reference
                    for i = 1:length(app.Files)
                        %Names in the list box
                        loadDataFileName = app.Files(i).name;
                        fileName = strsplit(loadDataFileName,"."); 
                        ShiftName = "AfterFocus" + fileName(1);
    
                        app.ShiftFilesListBox.Items = [app.ShiftFilesListBox.Items, ShiftName];
                    end
                case '2' % Holograms
                    for i = 1:length(app.Files)
                        %Names in the list box
                        loadDataFileName = app.Files(i).name;
                        fileName = strsplit(loadDataFileName,"."); 
                        ShiftName = fileName(1);
    
                        app.ShiftFilesListBox.Items = [app.ShiftFilesListBox.Items, ShiftName];
                    end
                case '3' %
                   for i = 1:length(app.Files)
                        %Names in the list box
                        loadDataFileName = app.Files(i).name;
                        fileName = strsplit(loadDataFileName,"."); 
                        ShiftName = "CorrectedAfterFocus"+fileName(1);
        
                        app.ShiftFilesListBox.Items = [app.ShiftFilesListBox.Items, ShiftName];
                   end
                case '4'
                   for i = 1:length(app.Files)
                        %Names in the list box
                        loadDataFileName = app.Files(i).name;
                        fileName = strsplit(loadDataFileName,"."); 
                        ShiftName = "Corrected"+fileName(1);
        
                        app.ShiftFilesListBox.Items = [app.ShiftFilesListBox.Items, ShiftName];
                   end
            end

             app.LoadDataFilesListBox.ItemsData = [1:length(app.Files)];
             app.LoadDataFilesListBox.Value = app.CurrentHologramIndex;
             app.DisplayShift();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackLayout = uigridlayout(app.UIFigure);
            app.SideLayout = uigridlayout(app.BackLayout);
            app.MainLayout = uigridlayout(app.SideLayout);
            app.NavigationTab = uitabgroup(app.MainLayout);
            app.LoadDataTab = uitab(app.NavigationTab);
            app.LoadDataTab.Title = 'Load Data';
            app.LoadDataLayout = uigridlayout(app.LoadDataTab);
            app.LoadDataControlLayout = uigridlayout(app.LoadDataLayout);
            app.LoadDataFilesPanel = uipanel(app.LoadDataControlLayout);
            app.LoadDataFileListBoxLayout = uigridlayout(app.LoadDataFilesPanel);
            app.FilesListBox_3Label = uilabel(app.LoadDataFileListBoxLayout);
            app.FilesListBox_3Label.Text = 'Files';
            app.LoadDataFilesListBox = uilistbox(app.LoadDataFileListBoxLayout);
            app.LoadDataFilesListBox.Items = {};
            app.LoadDataFilesListBox.ValueChangedFcn = createCallbackFcn(app, @LoadDataFilesListBoxValueChanged, true);
            app.LoadDataFilesListBox.Value = {};
            app.LoadHologramsButton = uibutton(app.LoadDataControlLayout, 'push');
            app.LoadHologramsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHologramsButtonPushed, true);
            app.LoadHologramsButton.Text = 'Load Holograms';
            app.GridLayout = uigridlayout(app.LoadDataControlLayout);
            app.DefaultvaluesLabel = uilabel(app.GridLayout);
            app.DefaultvaluesLabel.Text = 'Default values';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.GridLayout);
            app.ModeDropDown.Items = {'Single hologram', 'Multi-wavelenght', 'Multi-height'};
            app.ModeDropDown.ItemsData = {'1', '2', '3', ''};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = '1';
            app.ModeLabel = uilabel(app.GridLayout);
            app.ModeLabel.Text = 'Mode';
            app.ModeTabGroup = uitabgroup(app.GridLayout);
            app.ModeTabGroup.Visible = 'off';
            app.MultiwavelenghtTab = uitab(app.ModeTabGroup);
            app.MultiwavelenghtTab.Title = 'Multi-wavelenght';
            app.GridLayout2_2 = uigridlayout(app.MultiwavelenghtTab);
            app.PhotoWavelengthTable = uitable(app.GridLayout2_2);
            app.PhotoWavelengthTable.ColumnName = {'Photo'; 'Wavelength [μm]'};
            app.PhotoWavelengthTable.RowName = {};
            app.PhotoWavelengthTable.ColumnEditable = [false true];
            app.PhotoWavelengthTable.CellEditCallback = createCallbackFcn(app, @PhotoWavelengthTableCellEdit, true);
            app.MultiheightTab = uitab(app.ModeTabGroup);
            app.MultiheightTab.Title = 'Multi-height';
            app.GridLayout2 = uigridlayout(app.MultiheightTab);
            app.PhotoHeightTable = uitable(app.GridLayout2);
            app.PhotoHeightTable.ColumnName = {'Photo'; 'Height [μm]'};
            app.PhotoHeightTable.RowName = {};
            app.PhotoHeightTable.ColumnEditable = [false true];
            app.PhotoHeightTable.CellEditCallback = createCallbackFcn(app, @PhotoHeightTableCellEdit, true);
            app.HeightmLabel = uilabel(app.GridLayout);
            app.HeightmLabel.Text = 'Height           [μm]';
            app.HeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.PixelSizemLabel = uilabel(app.GridLayout);
            app.PixelSizemLabel.Text = 'Pixel Size      [μm]';
            app.PixelSizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.PixelSizeEditField.Limits = [1 Inf];
            app.PixelSizeEditField.Value = 2.4;
            app.WavelengthmEditFieldLabel = uilabel(app.GridLayout);
            app.WavelengthmEditFieldLabel.Text = 'Wavelength [μm]';
            app.WavelengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.WavelengthEditField.Limits = [0 Inf];
            app.WavelengthEditField.Value = 0.561;
            app.LoadDataDisplayLayout = uigridlayout(app.LoadDataLayout);
            app.LoadDataUIAxes = uiaxes(app.LoadDataDisplayLayout);
            app.LoadDataUIAxes.XTick = [];
            app.LoadDataUIAxes.YTick = [];
            app.LoadDataUIAxes.ZTick = [];
            app.LoadDataDisplayButtonsLayout = uigridlayout(app.LoadDataDisplayLayout);
            app.LoadDataPreviousButton = uibutton(app.LoadDataDisplayButtonsLayout, 'push');
            app.LoadDataPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.LoadDataPreviousButton.Text = '<';
            app.LoadDataNextButton = uibutton(app.LoadDataDisplayButtonsLayout, 'push');
            app.LoadDataNextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.LoadDataNextButton.Text = '>';
            app.FocusTab = uitab(app.NavigationTab);
            app.FocusTab.Title = 'Focus';
            app.FocusLayout = uigridlayout(app.FocusTab);
            app.FocusControlLayout = uigridlayout(app.FocusLayout);
            app.FocusFilesPanel = uipanel(app.FocusControlLayout);
            app.FocusFileListBoxLayout = uigridlayout(app.FocusFilesPanel);
            app.FilesListBox_2Label = uilabel(app.FocusFileListBoxLayout);
            app.FilesListBox_2Label.Text = 'Files';
            app.FocusFilesListBox = uilistbox(app.FocusFileListBoxLayout);
            app.FocusFilesListBox.Items = {};
            app.FocusFilesListBox.ValueChangedFcn = createCallbackFcn(app, @FocusFilesListBoxValueChanged, true);
            app.FocusFilesListBox.Value = {};
            app.FocusingPanel = uipanel(app.FocusControlLayout);
            app.AdjustFocusLayout = uigridlayout(app.FocusingPanel);
            app.ControlLayout = uigridlayout(app.AdjustFocusLayout);
            app.StepLabel = uilabel(app.ControlLayout);
            app.StepLabel.Text = 'Step [μm]';
            app.StepEditField = uieditfield(app.ControlLayout, 'numeric');
            app.StepEditField.Limits = [1 Inf];
            app.StepEditField.Value = 100;
            app.ControlLayout_2 = uigridlayout(app.AdjustFocusLayout);
            app.CurrentHeightmLabel = uilabel(app.ControlLayout_2);
            app.CurrentHeightmLabel.Text = 'Current Height [μm]';
            app.CurrentHeightEditField = uieditfield(app.ControlLayout_2, 'numeric');
            app.CurrentHeightEditField.Limits = [0 Inf];
            app.CurrentHeightEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentHeightEditFieldValueChanged, true);
            app.AddButton = uibutton(app.ControlLayout_2, 'state');
            app.AddButton.ValueChangedFcn = createCallbackFcn(app, @AddButtonValueChanged, true);
            app.AddButton.Text = '+';
            app.SubtractButton = uibutton(app.ControlLayout_2, 'state');
            app.SubtractButton.ValueChangedFcn = createCallbackFcn(app, @SubtractButtonValueChanged, true);
            app.SubtractButton.Text = '-';
            app.SweepAutoFocusLayout = uigridlayout(app.AdjustFocusLayout);
            app.DarkfocusLabel = uilabel(app.SweepAutoFocusLayout);
            app.DarkfocusLabel.Text = 'Dark focus';
            app.StartmLabel = uilabel(app.SweepAutoFocusLayout);
            app.StartmLabel.Text = {'Start '; '[μm]'};
            app.SweepStartEditField = uieditfield(app.SweepAutoFocusLayout, 'numeric');
            app.SweepStepLabel = uilabel(app.SweepAutoFocusLayout);
            app.SweepStepLabel.Text = {'Range'; '[μm]'};
            app.DarkFocusRangeEditField = uieditfield(app.SweepAutoFocusLayout, 'numeric');
            app.SweepStopLabel = uilabel(app.SweepAutoFocusLayout);
            app.SweepStopLabel.Text = {'Step '; '[μm]'};
            app.SweepStopEditField = uieditfield(app.SweepAutoFocusLayout, 'numeric');
            app.AutofocusButton = uibutton(app.SweepAutoFocusLayout, 'push');
            app.AutofocusButton.Text = 'Auto focus';
            app.AdjustFocusLabel = uilabel(app.AdjustFocusLayout);
            app.AdjustFocusLabel.Text = 'Adjust Focus';
            app.FocusDisplayLayout = uigridlayout(app.FocusLayout);
            app.FocusUIAxes = uiaxes(app.FocusDisplayLayout);
            app.FocusUIAxes.XTick = [];
            app.FocusUIAxes.YTick = [];
            app.FocusUIAxes.ZTick = [];
            app.FocusDisplayButtonsLayout = uigridlayout(app.FocusDisplayLayout);
            app.FocusPreviousButton = uibutton(app.FocusDisplayButtonsLayout, 'push');
            app.FocusPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.FocusPreviousButton.Text = '<';
            app.FocusNextButton = uibutton(app.FocusDisplayButtonsLayout, 'push');
            app.FocusNextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.FocusNextButton.Text = '>';
            app.AmplitudeButton = uibutton(app.FocusDisplayButtonsLayout, 'state');
            app.AmplitudeButton.ValueChangedFcn = createCallbackFcn(app, @AmplitudeButtonValueChanged, true);
            app.AmplitudeButton.Text = 'Amplitude';
            app.AmplitudeButton.Value = true;
            app.PhaseButton = uibutton(app.FocusDisplayButtonsLayout, 'state');
            app.PhaseButton.ValueChangedFcn = createCallbackFcn(app, @PhaseButtonValueChanged, true);
            app.PhaseButton.Text = 'Phase';
            app.ShiftTab = uitab(app.NavigationTab);
            app.ShiftTab.Title = 'Shift';
            app.ShiftLayout = uigridlayout(app.ShiftTab);
            app.ShiftDisplayLayout = uigridlayout(app.ShiftLayout);
            app.ShiftUIAxes = uiaxes(app.ShiftDisplayLayout);
            app.ShiftUIAxes.XTick = [];
            app.ShiftUIAxes.YTick = [];
            app.ShiftUIAxes.ZTick = [];
            app.ShiftDisplayButtonsLayout = uigridlayout(app.ShiftDisplayLayout);
            app.ShiftPreviousButton = uibutton(app.ShiftDisplayButtonsLayout, 'push');
            app.ShiftPreviousButton.Text = '<';
            app.ShiftNextButton = uibutton(app.ShiftDisplayButtonsLayout, 'push');
            app.ShiftNextButton.Text = '>';
            app.ShiftAmplitudeButton = uibutton(app.ShiftDisplayButtonsLayout, 'state');
            app.ShiftAmplitudeButton.Text = 'Amplitude';
            app.ShiftAmplitudeButton.Value = true;
            app.ShiftPhaseButton = uibutton(app.ShiftDisplayButtonsLayout, 'state');
            app.ShiftPhaseButton.Text = 'Phase';
            app.ShiftControlLayout = uigridlayout(app.ShiftLayout);
            app.ShiftFilesPanel = uipanel(app.ShiftControlLayout);
            app.ShiftFileListBoxLayout = uigridlayout(app.ShiftFilesPanel);
            app.FilesListBox_3Label_2 = uilabel(app.ShiftFileListBoxLayout);
            app.FilesListBox_3Label_2.Text = 'Files';
            app.ShiftFilesListBox = uilistbox(app.ShiftFileListBoxLayout);
            app.ShiftFilesListBox.Items = {''};
            app.ShiftFilesListBox.Value = {};
            app.GridLayout3_2 = uigridlayout(app.ShiftControlLayout);
            app.CorrectShiftLabel_2 = uilabel(app.GridLayout3_2);
            app.CorrectShiftLabel_2.Text = 'Correct Shift';
            app.AutocorrectButton = uibutton(app.GridLayout3_2, 'push');
            app.AutocorrectButton.ButtonPushedFcn = createCallbackFcn(app, @AutocorrectButtonPushed, true);
            app.AutocorrectButton.Text = 'Auto correct';
            app.GridLayout4 = uigridlayout(app.ShiftControlLayout);
            app.ShiftHologramTypeDropDown = uidropdown(app.GridLayout4);
            app.ShiftHologramTypeDropDown.Items = {'Reconstruction', 'Holograms', 'Shifted Reconstruction', 'Shifted Holograms'};
            app.ShiftHologramTypeDropDown.ItemsData = {'1', '2', '3', '4'};
            app.ShiftHologramTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShiftHologramTypeDropDownValueChanged, true);
            app.ShiftHologramTypeDropDown.Value = '1';
            app.ReconstructionTab = uitab(app.NavigationTab);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.LoadDataLayout_3 = uigridlayout(app.ReconstructionTab);
            app.ShiftDisplayLayout_2 = uigridlayout(app.LoadDataLayout_3);
            app.ShiftUIAxes_2 = uiaxes(app.ShiftDisplayLayout_2);
            app.ShiftUIAxes_2.XTick = [];
            app.ShiftUIAxes_2.YTick = [];
            app.ShiftUIAxes_2.ZTick = [];
            app.ShiftDisplayButtonsLayout_2 = uigridlayout(app.ShiftDisplayLayout_2);
            app.ShiftPreviousButton_2 = uibutton(app.ShiftDisplayButtonsLayout_2, 'push');
            app.ShiftPreviousButton_2.Text = '<';
            app.ShiftNextButton_2 = uibutton(app.ShiftDisplayButtonsLayout_2, 'push');
            app.ShiftNextButton_2.Text = '>';
            app.AmplitudeButton_3 = uibutton(app.ShiftDisplayButtonsLayout_2, 'state');
            app.AmplitudeButton_3.Text = 'Amplitude';
            app.AmplitudeButton_3.Value = true;
            app.PhaseButton_3 = uibutton(app.ShiftDisplayButtonsLayout_2, 'state');
            app.PhaseButton_3.Text = 'Phase';
            app.ShiftControlLayout_2 = uigridlayout(app.LoadDataLayout_3);
            app.ShiftFilesPanel_2 = uipanel(app.ShiftControlLayout_2);
            app.ShiftFileListBoxLayout_2 = uigridlayout(app.ShiftFilesPanel_2);
            app.FilesListBox_3Label_3 = uilabel(app.ShiftFileListBoxLayout_2);
            app.FilesListBox_3Label_3.Text = 'Files';
            app.ShiftFilesListBox_2 = uilistbox(app.ShiftFileListBoxLayout_2);
            app.ShiftFilesListBox_2.Items = {''};
            app.ShiftFilesListBox_2.Value = {};
            app.GridLayout3_3 = uigridlayout(app.ShiftControlLayout_2);
            app.ReconstructioncontrolLabel = uilabel(app.GridLayout3_3);
            app.ReconstructioncontrolLabel.Text = 'Reconstruction control';
            app.StartReconstruction = uibutton(app.GridLayout3_3, 'push');
            app.StartReconstruction.Text = 'Start';
            app.ResultsTab = uitab(app.NavigationTab);
            app.ResultsTab.Title = 'Results';
            app.ResultsLayout = uigridlayout(app.ResultsTab);
            app.LogoNavLayout = uigridlayout(app.SideLayout);
            app.Image = uiimage(app.LogoNavLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Najlepsze_logo.png');
            app.NavigationLayout = uigridlayout(app.LogoNavLayout);
            app.FocusButton = uibutton(app.NavigationLayout, 'state');
            app.FocusButton.ValueChangedFcn = createCallbackFcn(app, @FocusButtonValueChanged, true);
            app.FocusButton.Text = 'Focus';
            app.ReconstructionButton = uibutton(app.NavigationLayout, 'state');
            app.ReconstructionButton.Text = 'Reconstruction';
            app.ResultsButton = uibutton(app.NavigationLayout, 'state');
            app.ResultsButton.Text = 'Results';
            app.LoadDataButton = uibutton(app.NavigationLayout, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ShiftButton = uibutton(app.NavigationLayout, 'state');
            app.ShiftButton.ValueChangedFcn = createCallbackFcn(app, @ShiftButtonValueChanged, true);
            app.ShiftButton.Text = 'Shift';
            app.ModePanel = uipanel(app.UIFigure);
            app.ModePanel.Enable = 'off';
            app.ModePanel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoloApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end